classdef protocolchecker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProtocolCheckerUIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Panel                    matlab.ui.container.Panel
        MainGrid                 matlab.ui.container.GridLayout
        FilePathField            matlab.ui.control.EditField
        BrowseButton             matlab.ui.control.Button
        RefreshButton            matlab.ui.control.Button
        TrialTable               matlab.ui.control.Table
        StatusPanel              matlab.ui.container.Panel
        statusGrid               matlab.ui.container.GridLayout
        PlotButton               matlab.ui.control.Button
        InformationLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        p = [];
        g = [];
        err = [];
        figs = {};
    end
    
    methods (Access = private)
        
        function fpath = LoadDlg(app)
            folder = mfilename('fullpath');
            idx = strfind(folder, ['common' filesep 'checkprotocol']) - 1;
            folder = [folder(1:idx) 'config' filesep 'experiment_protocols' filesep '*.stim'];
            [filename, dir] = uigetfile(folder);
            if filename == 0
                fpath = [];
                return
            end
            fpath = [dir filesep filename];
        end

        function [p, g, err] = ProcessFile(app, fpath)
            p = [];
            g = [];
            err = [];
            if contains(fpath, '.stim')
                try
                    [p, g] = readProtocol(fpath, true);
                catch err
                    err = err.message;
                end
            else
                err = "Unsupported file format. Supported formats: .stim";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BrowseTestProtocols;
        end

        % Callback function: PlotButton, TrialTable
        function PlotTrial(app, event)
            if isempty(app.p)
                return
            end
            index = app.TrialTable.Selection;
            if isempty(index)
                for i = 1:length(app.p)
                    trial = app.p(i);
                    app.figs{end+1} = trial.Plot();
                end
            else
                trial = app.p(index);
                app.figs{end+1} = trial.Plot();
            end
        end

        % Selection changed function: TrialTable
        function InspectTrial(app, event)
            index = app.TrialTable.Selection;
            if isempty(index)
                app.PlotButton.Text = 'Plot All';
                return
            end
            app.PlotButton.Text = 'Plot';
            trial = app.p(index);
            if trial.valid
                statusMsg = "valid";
            else
                statusMsg = "INVALID";
            end
            params = strsplit(trial.line, '%');
            params = params{1};
            params = strtrim(params);
            app.InformationLabel.Text = sprintf("Trial %d - %s \n(%s) \n\n" + ...
                " %s \n\n" + ...
                "%s ", index, statusMsg, trial.comment, params, trial.errorMsg);                
        end

        % Button pushed function: BrowseButton
        function BrowseTestProtocols(app, event)
            fpath = app.LoadDlg;
            if isempty(fpath)
                return
            end
            app.FilePathField.Value = fpath;
            app.LoadTestProtocol;
        end

        % Button pushed function: RefreshButton
        function LoadTestProtocol(app, event)
            fpath = app.FilePathField.Value;
            app.p = [];
            app.g = [];
            app.err = [];
            [app.p, app.g, app.err] = ProcessFile(app, fpath);
    
            if ~isempty(app.err)
                app.InformationLabel.Text = sprintf("Error reading protocol: %s", app.err);
            else
                app.InformationLabel.Text = "Protocol Loaded Successfully.";
            end
    
            % fill out table
            tData = table();
            for i = 1:length(app.p)
                trial = app.p(i);
                if trial.valid
                    statusMsg = "valid";
                else
                    statusMsg = sprintf("INVALID");
                end
                tData{end+1, :} = {i, statusMsg};
            end
            app.TrialTable.Data = tData;
        end

        % Close request function: ProtocolCheckerUIFigure
        function ProtocolCheckerUIFigureCloseRequest(app, event)
            for i = 1:length(app.figs)
                fig = app.figs{i};
                if ~isempty(fig) && isvalid(fig)
                    delete(fig);
                end
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProtocolCheckerUIFigure = uifigure('Visible', 'off');
            app.ProtocolCheckerUIFigure.Name = 'Protocol Checker';
            app.ProtocolCheckerUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProtocolCheckerUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ProtocolCheckerUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.MainGrid = uigridlayout(app.Panel);
            app.StatusPanel = uipanel(app.MainGrid);
            app.statusGrid = uigridlayout(app.StatusPanel);
            app.InformationLabel = uilabel(app.statusGrid);
            app.InformationLabel.Text = 'Trial Information, when selected.';
            app.PlotButton = uibutton(app.statusGrid, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrial, true);
            app.PlotButton.Tooltip = {'Refresh the contents of the TextArea above to match the contents of the file it was loaded from'};
            app.PlotButton.Text = 'Plot';
            app.TrialTable = uitable(app.MainGrid);
            app.TrialTable.ColumnName = {'Trial No.'; 'Status'};
            app.TrialTable.RowName = {};
            app.TrialTable.SelectionType = 'row';
            app.TrialTable.DoubleClickedFcn = createCallbackFcn(app, @PlotTrial, true);
            app.TrialTable.SelectionChangedFcn = createCallbackFcn(app, @InspectTrial, true);
            app.TrialTable.Multiselect = 'off';
            app.RefreshButton = uibutton(app.MainGrid, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestProtocol, true);
            app.RefreshButton.Tooltip = {'Refresh the current file with new changes.'};
            app.RefreshButton.Text = 'Refresh';
            app.BrowseButton = uibutton(app.MainGrid, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseTestProtocols, true);
            app.BrowseButton.Tooltip = {'Select a file to parse'};
            app.BrowseButton.Text = 'Browse';
            app.FilePathField = uieditfield(app.MainGrid, 'text');
            app.FilePathField.Value = 'test';
            % Show the figure after all components are created
            app.ProtocolCheckerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = protocolchecker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProtocolCheckerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProtocolCheckerUIFigure)
        end
    end
end